classdef GWAS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        CalculateButton     matlab.ui.control.Button
        UITable             matlab.ui.control.Table
        LoadSNPsInfoButton  matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        Image               matlab.ui.control.Image
        CaGeAppGWASLabel    matlab.ui.control.Label
        VUT_logo            matlab.ui.control.Image
    end

properties (Access = private)
        MainApp;
        rawFileName;  % Name of file where data is located
        rawFilePath;  % Name of file path
        Geno;        % Genomic data
        Phe;
        InfoSNP;
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, geno_info, pheno_info)
            app.Geno = geno_info; % Genomic data
            app.Phe = pheno_info; % Phenotype data
            
            %Store main app in property
            app.MainApp = main_app;

        end

        % Button pushed function: LoadSNPsInfoButton
        function LoadSNPsInfoButtonPushed(app, event)
        [app.rawFileName,app.rawFilePath] = uigetfile({'*.xlsx;*.xls','All Excel tables';...
                '*.txt;*.csv;*.dat','All tab-seperated text files';...
                '*.*','All Files'},'Load a dataset');

            if app.rawFileName > 0
               % **********************************************************
               % reading excel files, assume Excel has been installed
              try 
                 %toto je zde divne%%%
                 app.InfoSNP = readtable(strcat(app.rawFilePath,app.rawFileName));
                 %toto je zde divne%%%
                 app.UITable.Data = app.InfoSNP;
                 varHeadTable = app.UITable.Data;
                 varHeadTable.Properties.VariableNames(1) = " ";
                 Names_varHeadTable = varHeadTable.Properties.VariableNames;
                 app.UITable.ColumnName = Names_varHeadTable;
                 catch err
                     hErrIdx = warndlg(['Fails to open file: ',err.message]);
                     uiwait(hErrIdx);
                 return;
              end
            end     
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            %% treatment of the transposition matrix
             % Extracting column names from the app.Geno table and
             % preprocessed
            column_names = app.Geno.Properties.VariableNames;
            column_names(1) = [];
            text_column = app.Geno{:,1};
            text_column = cellstr(text_column);

            numeric_columns = app.Geno{:,2:end};
            transposed_numeric_data = numeric_columns';
            transposed_data = array2table(transposed_numeric_data);
            transposed_data.Properties.VariableNames = text_column';
            transposed_column_names = column_names';
            combined_data = [transposed_column_names, num2cell(transposed_numeric_data)];
            new = cell2table(combined_data);
            new.Properties.VariableNames = ['ColumnNames', text_column'];

            %%
            new.Properties.VariableNames(1) = app.Phe.Properties.VariableNames(1);
            app.Phe.Properties.VariableNames{2} = 'phenotype';
            data = outerjoin(new, app.Phe, 'MergeKeys', true);
            % Perform association testing for each SNP
            results = table();
            genotype_data_columns = data.Properties.VariableNames;
            genotype_data_columns = genotype_data_columns(~strcmp(genotype_data_columns, data.Properties.VariableNames(1)));
            
            for i = 1:length(genotype_data_columns)
                snp = genotype_data_columns{i};
                x = data.(snp);
                x = [ones(size(x)), x];
                y = data.phenotype;  % Corrected line
                [beta,~,~,~,stats] = regress(y, x);  % Fit linear regression model
                beta = beta(2);  % Coefficient represents effect size
                p_value = stats(3);  % Calculation p-value
                results = [results; table({snp}, p_value, beta)]; % Use cell array for the SNP variable
            end
            
            effect_sizes = results(:, {'Var1', 'beta'});
            results = removevars(results, 'beta');
            
            % Merge SNP information data with results
            app.InfoSNP.Properties.VariableNames(1) = "Var1";
            results.Var1(1:size(app.InfoSNP,1)) = app.InfoSNP.Var1;
            manhattan_data = innerjoin(results(1:size(app.InfoSNP,1),:), app.InfoSNP(:, {'Var1', 'Chromosome', 'Position'}), 'Keys', 'Var1');
            
            % Calculate -log10(P-value)
            manhattan_data.NegLogP = -log10(manhattan_data.p_value);
            
            % Calculate Bonferroni correction threshold
            num_tests = length(genotype_data_columns);
            bonferroni_threshold = 0.05 / num_tests;
            bonferroni_threshold_log = -log10(bonferroni_threshold);
            
            % Plot Manhattan plot
            colors = hsv(max(manhattan_data.Chromosome)); % Define colors for each chromosome
            cumulative_max_position = 0; % Initialize cumulative maximum position
            hold(app.UIAxes, 'on');
            for chr = unique(manhattan_data.Chromosome)'
                chr_data = manhattan_data(manhattan_data.Chromosome == chr, :);
                scatter(app.UIAxes, chr_data.Position + cumulative_max_position, chr_data.NegLogP, 20, colors(chr, :), 'filled', 'DisplayName', ['Chromosome ' num2str(chr)]);
                cumulative_max_position = cumulative_max_position + max(chr_data.Position); % Update cumulative maximum position
            end


            % Plot Bonferroni correction line
            yline(app.UIAxes, bonferroni_threshold_log, 'r--', 'LineWidth', 2, 'DisplayName', 'Bonferroni Threshold');
                        
            hold(app.UIAxes, 'off');
            xlabel(app.UIAxes, 'Genomic Position');
            ylabel(app.UIAxes, '-log10(P-Value)');
            title(app.UIAxes, 'Manhattan Plot for GWAS');
            legend(app.UIAxes, 'Location', 'bestoutside');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VUT_logo = uiimage(app.UIFigure);
            app.VUT_logo.ImageSource = 'BUT_logo_T.png';
            app.CaGeAppGWASLabel = uilabel(app.UIFigure);
            app.CaGeAppGWASLabel.Text = ' CaGeApp – GWAS';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MoSys_logo.png');
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadSNPsInfoButton = uibutton(app.Panel, 'push');
            app.LoadSNPsInfoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSNPsInfoButtonPushed, true);
            app.LoadSNPsInfoButton.Text = 'Load SNPs Info';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GWAS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end