classdef PRS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DownloadResultsButton       matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        PRSPolygenicRiskScoreLabel  matlab.ui.control.Label
        CalculatePRSButton          matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        PRS_hist_gauss              matlab.ui.control.UIAxes
    end

properties (Access = private)
        MainApp;
        Geno;        % Genomic data
        Phe;

        results;
end

methods (Access = private)

        function updatePRS_hist_gauss(app)
            histfit(app.PRS_hist_gauss, app.results.p_value);
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, geno_info, pheno_info)
            app.Geno = geno_info; % Genomic data
            app.Phe = pheno_info; % Phenotype data
            
            %Store main app in property
            app.MainApp = main_app;

        end

        % Callback function: CalculatePRSButton, PRS_hist_gauss
        function CalculatePRSButtonPushed(app, event)
            app.Geno = rows2vars(app.Geno);
            app.Geno(1, :) = [];
            app.Geno.Properties.VariableNames(1) = app.Phe.Properties.VariableNames(1);
            data = outerjoin(app.Geno, app.Phe, 'MergeKeys', true);

            % Perform association testing for each SNP
            app.results = table();
            genotype_data_columns = app.Geno.Properties.VariableNames;
            genotype_data_columns = genotype_data_columns(~strcmp(genotype_data_columns, app.Geno.Properties.VariableNames(1)));
            
            for i = 1:length(genotype_data_columns)
                snp = genotype_data_columns{i};
                x = data.(snp);
                if iscell(x)
                    x = cell2mat(x);
                end
                x = [ones(size(x)), x];
                lastColumnName = app.Phe.Properties.VariableNames{end};
                y = data.(lastColumnName);  % Corrected line
                [beta,~,~,~,stats] = regress(y, x);  % Fit linear regression model
                beta = beta(2);  % Coefficient represents effect size
                p_value = stats(3);  % Calculation p-value
                app.results = [app.results; table({snp}, p_value, beta)]; % Use cell array for the SNP variable
            end
            
            effect_sizes = app.results(:, {'Var1', 'beta'});
            app.results = removevars(app.results, 'beta');

             app.UITable.Data = app.results;
             varHeadTable = app.UITable.Data;
             varHeadTable.Properties.VariableNames(1) = " ";
             Names_varHeadTable = varHeadTable.Properties.VariableNames;
             app.UITable.ColumnName = Names_varHeadTable;
             
             updatePRS_hist_gauss(app);

        end

        % Button pushed function: DownloadResultsButton
        function DownloadResultsButtonPushed(app, event)
            outputFolder = 'outputs_PRS';

            if ~exist(outputFolder, 'dir')
               mkdir(outputFolder);
            end
            
            filename = fullfile(outputFolder, 'results.xlsx');
            writetable(app.results, filename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PRS_hist_gauss = uiaxes(app.UIFigure);
            zlabel(app.PRS_hist_gauss, 'Z')
            app.PRS_hist_gauss.ButtonDownFcn = createCallbackFcn(app, @CalculatePRSButtonPushed, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BUT_logo_T.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'MoSys_logo.png');
            app.CalculatePRSButton = uibutton(app.UIFigure, 'push');
            app.CalculatePRSButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePRSButtonPushed, true);
            app.CalculatePRSButton.Text = 'Calculate PRS';
            app.PRSPolygenicRiskScoreLabel = uilabel(app.UIFigure);
            app.PRSPolygenicRiskScoreLabel.Text = '   PRS: Polygenic Risk Score';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.DownloadResultsButton = uibutton(app.UIFigure, 'push');
            app.DownloadResultsButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadResultsButtonPushed, true);
            app.DownloadResultsButton.Text = 'Download Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PRS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end